classdef HarshitaJoshiMinorProject < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                 matlab.ui.Figure
        VoiceBasedGenderIdentificationSystemPanel  matlab.ui.container.Panel
        StartRecordingButton     matlab.ui.control.Button
        PlayRecordingButton      matlab.ui.control.Button
        UIAxes                   matlab.ui.control.UIAxes
        DisplayRecordedAudioWaveformButton  matlab.ui.control.Button
        MaleLampLabel            matlab.ui.control.Label
        MaleLamp                 matlab.ui.control.Lamp
        FemaleLampLabel          matlab.ui.control.Label
        FemaleLamp               matlab.ui.control.Lamp
        GenderIdentifiedAsLabel  matlab.ui.control.Label
    end


    properties (Access = public)
        arObj;
        fs;
        y;
        r;
        ms20;
    end  

    methods (Access = private)

        % Button pushed function: DisplayRecordedAudioWaveformButton
        function DisplayRecordedAudioWaveformButtonPushed(app, event)
           plot(app.UIAxes,app.y);
        end

        % Button pushed function: PlayRecordingButton
        function PlayRecordingButtonPushed(app, event)
            app.y = getaudiodata(app.arObj);
            sound(app.y);
            ms2 = app.fs/500;
            
            app.ms20 = app.fs/50;
            app.r = xcorr(app.y, app.ms20, 'coeff');
            app.r = app.r(app.ms20 + 1 : 2*app.ms20+1);
            
            [rmax, tx] = max(app.r(ms2:app.ms20));
            fth = 160;
            Fx = app.fs/(ms2+tx-1);
            if Fx>=fth
            app.FemaleLamp.Color = 'g';
            elseif Fx>=80 && Fx<fth
            app.MaleLamp.Color = 'g';
            end 
        end

        % Button pushed function: StartRecordingButton
        function StartRecordingButtonPushed(app, event)
             app.fs=8000;                      % Sampling rate
            
             nbits=16;
            
            nChannels=1;
            
            duration=5;                 % Recording duration
            
            app.arObj=audiorecorder(app.fs, nbits, nChannels);
            msgbox('Recording Started');
            %pause;
            recordblocking(app.arObj, duration);
            msgbox('Recording Stopped');
           
        end
    end

    % App initialization and construction
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure;
            app.UIFigure.Name = 'UI Figure';
            app.VoiceBasedGenderIdentificationSystemPanel = uipanel(app.UIFigure);
            app.VoiceBasedGenderIdentificationSystemPanel.Title = 'Voice Based Gender Identification System';
            app.StartRecordingButton = uibutton(app.VoiceBasedGenderIdentificationSystemPanel, 'push');
            app.StartRecordingButton.ButtonPushedFcn = createCallbackFcn(app, @StartRecordingButtonPushed, true);
            app.StartRecordingButton.Text = 'Start Recording';
            app.PlayRecordingButton = uibutton(app.VoiceBasedGenderIdentificationSystemPanel, 'push');
            app.PlayRecordingButton.ButtonPushedFcn = createCallbackFcn(app, @PlayRecordingButtonPushed, true);
            app.PlayRecordingButton.Text = 'Play Recording';
            app.UIAxes = uiaxes(app.VoiceBasedGenderIdentificationSystemPanel);
            title(app.UIAxes, 'Audio Waveform')
            xlabel(app.UIAxes, 'X')
            ylabel(app.UIAxes, 'Y')
            app.DisplayRecordedAudioWaveformButton = uibutton(app.VoiceBasedGenderIdentificationSystemPanel, 'push');
            app.DisplayRecordedAudioWaveformButton.ButtonPushedFcn = createCallbackFcn(app, @DisplayRecordedAudioWaveformButtonPushed, true);
            app.DisplayRecordedAudioWaveformButton.Text = 'Display Recorded Audio Waveform';
            app.MaleLampLabel = uilabel(app.VoiceBasedGenderIdentificationSystemPanel);
            app.MaleLampLabel.Text = 'Male';
            app.MaleLamp = uilamp(app.VoiceBasedGenderIdentificationSystemPanel);
            app.FemaleLampLabel = uilabel(app.VoiceBasedGenderIdentificationSystemPanel);
            app.FemaleLampLabel.Text = 'Female';
            app.FemaleLamp = uilamp(app.VoiceBasedGenderIdentificationSystemPanel);
            app.GenderIdentifiedAsLabel = uilabel(app.VoiceBasedGenderIdentificationSystemPanel);
            app.GenderIdentifiedAsLabel.Text = 'Gender Identified As:';
        end
    end

    methods (Access = public)

        % Construct app
        function app = HarshitaJoshiMinorProject

            % Create and configure components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end